classdef mmc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MMCQueuingModelUIFigure       matlab.ui.Figure
        ControlsPanel                 matlab.ui.container.Panel
        StatusButton                  matlab.ui.control.Button
        SimulationTimeSlider          matlab.ui.control.Slider
        Simulationtimex103Label       matlab.ui.control.Label
        ExportDataButton              matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        SimulationProgress            simulink.ui.control.SimulationProgress
        ValidationPanel               matlab.ui.container.Panel
        TheoreticalValuesPanel        matlab.ui.container.Panel
        ServerUtilizationLabel_       matlab.ui.control.Label
        TimeAverageWaitLabel_         matlab.ui.control.Label
        TimeAverageQueueLengthLabel_  matlab.ui.control.Label
        ObservedValuesPanel           matlab.ui.container.Panel
        ServerUtilizationLabel        matlab.ui.control.Label
        TimeAverageWaitLabel          matlab.ui.control.Label
        TimeAverageQueueLengthLabel   matlab.ui.control.Label
        MetricsPanel                  matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        queue_length_Axes             matlab.ui.control.UIAxes
        util_server_Axes              matlab.ui.control.UIAxes
        wait_Axes                     matlab.ui.control.UIAxes
        ParameterPanel                matlab.ui.container.Panel
        MuLabel                       matlab.ui.control.Label
        LambdaLabel                   matlab.ui.control.Label
        CSpinner                      matlab.ui.control.Spinner
        ServerscSpinnerLabel          matlab.ui.control.Label
        MuKnob                        matlab.ui.control.Knob
        ServiceRatemuLabel            matlab.ui.control.Label
        LambdaKnob                    matlab.ui.control.Knob
        ArrivalRatelambdaLabel        matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        error_code = 0; % Description
    end
    
    methods (Access = private)
        
        function RunSimulation(app)
            app.Simulation = app.Simulation.setModelParameter('StopTime', num2str(app.SimulationTimeSlider.Value * 1000));
            app.Simulation = app.Simulation.setVariable('lambda', app.LambdaKnob.Value);
            app.Simulation = app.Simulation.setVariable('mu', app.MuKnob.Value);
            app.Simulation = app.Simulation.setVariable('c', app.CSpinner.Value);

            app.SimulationProgress.Simulation = app.Simulation;

            start(app.Simulation);
            
            while strcmp(app.Simulation.Status, 'running') 
                pause(1);  
            end
        end
        
        function ComputeMetrics(app)
            app.TimeAverageQueueLengthLabel.Text = append("Time-Average Queue Length ($\hat{L}_Q$): ", ...
                num2str(app.Simulation.SimulationOutput.yout.get('queue_length').Values.Data(end)), " entities");

            app.TimeAverageWaitLabel.Text = append("Time-Average Wait ($\hat{w}$): ", ...
                num2str(app.Simulation.SimulationOutput.yout.get('wait').Values.Data(end)), ...
                " time units");

            app.ServerUtilizationLabel.Text = append("Server Utilization ($\hat{\rho}$): ", ...
                num2str(app.Simulation.SimulationOutput.yout.get('util_server').Values.Data(end) * 100), ...
                " \%");
        end
        
        function PlotMetrics(app)
            plot(app.Simulation.SimulationOutput.yout.get('queue_length').Values, ...
                'Parent', app.queue_length_Axes,'LineWidth',2);
            app.queue_length_Axes.YLimitMethod = 'padded';

            plot(app.Simulation.SimulationOutput.yout.get('wait').Values, ...
                'Parent', app.wait_Axes,'LineWidth',2);
            app.wait_Axes.YLimitMethod = 'padded';

            plot(app.Simulation.SimulationOutput.yout.get('util_server').Values, ...
                'Parent', app.util_server_Axes,'LineWidth',2);
            app.util_server_Axes.YLim = [0 1];
            app.util_server_Axes.YLimitMethod = 'padded';
        end
        
        function Validate(app)
            lambda = app.LambdaKnob.Value;
            c = app.CSpinner.Value;
            mu = app.MuKnob.Value;

            rho = lambda / (c * mu);

            P0 = 0;
            for i = 0 : c - 1
                P0 = P0 + (((c * rho) ^ i) / factorial(i));
            end
            P0 = 1 / (P0 + (((c * rho) ^ c) * (1 / factorial(c)) * (1 / (1 - rho))));

            L = (c * rho) + (((c * rho) ^ (c + 1)) * P0) / (c * factorial(c) * (1 - rho) ^ 2);

            w = L / lambda;

            w_q = w - (1 / mu);

            L_q = lambda * w_q;

            app.TimeAverageQueueLengthLabel_.Text = append("Time-Average Queue Length ($L_Q$): ", ...
                num2str(L_q), " entities");

            app.TimeAverageWaitLabel_.Text = append("Time-Average Wait ($w$): ", ...
                num2str(w), " time units");

            app.ServerUtilizationLabel_.Text = append("Server Utilization ($\rho$): ", ...
                num2str(rho * 100), " \%");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.RunSimulation();
            app.ComputeMetrics();
            app.PlotMetrics();
            app.Validate();
            app.ExportDataButton.Enable = 'on';
        end

        % Button pushed function: StatusButton
        function StatusButtonPushed(app, event)
            if app.error_code == 1
                uialert(app.MMCQueuingModelUIFigure, ...
                    "If $\lambda \geq c\mu$, the system will not be in steady state.", ...
                    "Unsteady State", ...
                    'Interpreter','latex');
            elseif app.error_code == 2
                uialert(app.MMCQueuingModelUIFigure, ...
                    "Arrival rate must have a non-zero value.", ...
                    "Invalid Value", ...
                    'Interpreter','latex');
            end
        end

        % Value changing function: LambdaKnob
        function LambdaKnobValueChanging(app, event)
            app.LambdaLabel.Text = num2str(event.Value);
            if event.Value >= app.CSpinner.Value * app.MuKnob.Value
                app.StatusButton.Text = "&#x2612;";
                app.StatusButton.BackgroundColor = 'red';
                app.LambdaLabel.FontColor = 'red';
                app.StartButton.Enable = 'off';
                app.error_code = 1;
            elseif event.Value == 0
                app.StatusButton.Text = "&#x2612;";
                app.StatusButton.BackgroundColor = 'red';
                app.LambdaLabel.FontColor = 'red';
                app.StartButton.Enable = 'off';
                app.error_code = 2;
            else
                app.StatusButton.Text = "&#x2611;";
                app.StatusButton.BackgroundColor = 'green';
                app.LambdaLabel.FontColor = 'black';
                app.MuLabel.FontColor = 'black';
                app.CSpinner.FontColor = 'black';
                app.StartButton.Enable = 'on';
                app.error_code = 0;
            end
        end

        % Value changing function: MuKnob
        function MuKnobValueChanging(app, event)
            app.MuLabel.Text = num2str(event.Value);
            if (app.LambdaKnob.Value >= app.CSpinner.Value * event.Value)
                app.StatusButton.Text = "&#x2612;";
                app.StatusButton.BackgroundColor = 'red';
                app.MuLabel.FontColor = 'red';
                app.StartButton.Enable = 'off';
                app.error_code = 1;
            else
                app.StatusButton.Text = "&#x2611;";
                app.StatusButton.BackgroundColor = 'green';
                app.LambdaLabel.FontColor = 'black';
                app.MuLabel.FontColor = 'black';
                app.CSpinner.FontColor = 'black';
                app.StartButton.Enable = 'on';
                app.error_code = 0;
            end
        end

        % Value changing function: CSpinner
        function CSpinnerValueChanging(app, event)
            if (app.LambdaKnob.Value >= event.Value * app.MuKnob.Value)
                app.StatusButton.Text = "&#x2612;";
                app.StatusButton.BackgroundColor = 'red';
                app.CSpinner.FontColor = 'red';
                app.StartButton.Enable = 'off';
                app.error_code = 1;
            else
                app.StatusButton.Text = "&#x2611;";
                app.StatusButton.BackgroundColor = 'green';
                app.LambdaLabel.FontColor = 'black';
                app.MuLabel.FontColor = 'black';
                app.CSpinner.FontColor = 'black';
                app.StartButton.Enable = 'on';
                app.error_code = 0;
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            dt = string(datetime('now','Format','uuuuMMdd''T''HHmmss'));
            
            QL = timeseries2timetable(app.Simulation.SimulationOutput.yout.get('queue_length').Values);
            writetimetable(QL, strcat('mmc_results_[', dt, '].xlsx'), ...
                'Sheet', "Time-Average Queue Length");

            W = timeseries2timetable(app.Simulation.SimulationOutput.yout.get('wait').Values);
            writetimetable(W, strcat('mmc_results_[', dt, '].xlsx'), ...
                'Sheet', "Time-Average Wait");
            
            P = timeseries2timetable(app.Simulation.SimulationOutput.yout.get('util_server').Values);
            writetimetable(P, strcat('mmc_results_[', dt, '].xlsx'), ...
                'Sheet', "Server Utilization");

            app.ExportDataButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MMCQueuingModelUIFigure = uifigure('Visible', 'off');
            app.MMCQueuingModelUIFigure.Name = 'M/M/C Queuing Model';
            app.ParameterPanel = uipanel(app.MMCQueuingModelUIFigure);
            app.ParameterPanel.Title = 'Input Parameters';
            app.ArrivalRatelambdaLabel = uilabel(app.ParameterPanel);
            app.ArrivalRatelambdaLabel.Interpreter = 'latex';
            app.ArrivalRatelambdaLabel.Text = 'Arrival Rate ($\lambda$):';
            app.LambdaKnob = uiknob(app.ParameterPanel, 'continuous');
            app.LambdaKnob.Limits = [0 20];
            app.LambdaKnob.ValueChangingFcn = createCallbackFcn(app, @LambdaKnobValueChanging, true);
            app.LambdaKnob.Value = 2.5;
            app.ServiceRatemuLabel = uilabel(app.ParameterPanel);
            app.ServiceRatemuLabel.Interpreter = 'latex';
            app.ServiceRatemuLabel.Text = 'Service Rate ($\mu$):';
            app.MuKnob = uiknob(app.ParameterPanel, 'continuous');
            app.MuKnob.Limits = [0 20];
            app.MuKnob.ValueChangingFcn = createCallbackFcn(app, @MuKnobValueChanging, true);
            app.MuKnob.Value = 5;
            app.ServerscSpinnerLabel = uilabel(app.ParameterPanel);
            app.ServerscSpinnerLabel.Interpreter = 'latex';
            app.ServerscSpinnerLabel.Text = 'Servers ($c$)';
            app.CSpinner = uispinner(app.ParameterPanel);
            app.CSpinner.ValueChangingFcn = createCallbackFcn(app, @CSpinnerValueChanging, true);
            app.CSpinner.Limits = [1 5];
            app.CSpinner.RoundFractionalValues = 'on';
            app.CSpinner.Value = 3;
            app.LambdaLabel = uilabel(app.ParameterPanel);
            app.LambdaLabel.Text = '2.5';
            app.MuLabel = uilabel(app.ParameterPanel);
            app.MuLabel.Text = '5';
            app.MetricsPanel = uipanel(app.MMCQueuingModelUIFigure);
            app.MetricsPanel.Title = 'Performance Metrics';
            app.GridLayout = uigridlayout(app.MetricsPanel);
            app.wait_Axes = uiaxes(app.GridLayout);
            title(app.wait_Axes, 'Time-Average Wait')
            xlabel(app.wait_Axes, 't')
            ylabel(app.wait_Axes, 'w')
            zlabel(app.wait_Axes, 'Z')
            app.util_server_Axes = uiaxes(app.GridLayout);
            title(app.util_server_Axes, 'Server Utilization')
            xlabel(app.util_server_Axes, 't')
            ylabel(app.util_server_Axes, 'p')
            app.queue_length_Axes = uiaxes(app.GridLayout);
            title(app.queue_length_Axes, 'Time-Average Queue Length')
            xlabel(app.queue_length_Axes, 't')
            ylabel(app.queue_length_Axes, 'L_Q')
            zlabel(app.queue_length_Axes, 'Z')
            app.ValidationPanel = uipanel(app.MMCQueuingModelUIFigure);
            app.ValidationPanel.Title = 'Validation';
            app.ObservedValuesPanel = uipanel(app.ValidationPanel);
            app.ObservedValuesPanel.Title = 'Observed Values';
            app.TimeAverageQueueLengthLabel = uilabel(app.ObservedValuesPanel);
            app.TimeAverageQueueLengthLabel.Interpreter = 'latex';
            app.TimeAverageQueueLengthLabel.Text = 'Time-Average Queue Length ($\hat{L}_Q$): ';
            app.TimeAverageWaitLabel = uilabel(app.ObservedValuesPanel);
            app.TimeAverageWaitLabel.Interpreter = 'latex';
            app.TimeAverageWaitLabel.Text = 'Time-Average Wait ($\hat{w}$): ';
            app.ServerUtilizationLabel = uilabel(app.ObservedValuesPanel);
            app.ServerUtilizationLabel.Interpreter = 'latex';
            app.ServerUtilizationLabel.Text = 'Server Utilization ($\hat{\rho}$): ';
            app.TheoreticalValuesPanel = uipanel(app.ValidationPanel);
            app.TheoreticalValuesPanel.Title = 'Theoretical Values';
            app.TimeAverageQueueLengthLabel_ = uilabel(app.TheoreticalValuesPanel);
            app.TimeAverageQueueLengthLabel_.Interpreter = 'latex';
            app.TimeAverageQueueLengthLabel_.Text = 'Time-Average Queue Length ($L_Q$): ';
            app.TimeAverageWaitLabel_ = uilabel(app.TheoreticalValuesPanel);
            app.TimeAverageWaitLabel_.Interpreter = 'latex';
            app.TimeAverageWaitLabel_.Text = 'Time-Average Wait ($w$): ';
            app.ServerUtilizationLabel_ = uilabel(app.TheoreticalValuesPanel);
            app.ServerUtilizationLabel_.Interpreter = 'latex';
            app.ServerUtilizationLabel_.Text = 'Server Utilization ($\rho$): ';
            app.ControlsPanel = uipanel(app.MMCQueuingModelUIFigure);
            app.ControlsPanel.Title = 'Simulation Controls';
            app.SimulationProgress = uisimprogress(app.ControlsPanel);
            app.SimulationProgress.Simulation = app.Simulation;
            app.StartButton = uibutton(app.ControlsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExportDataButton = uibutton(app.ControlsPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.Simulationtimex103Label = uilabel(app.ControlsPanel);
            app.Simulationtimex103Label.Interpreter = 'latex';
            app.Simulationtimex103Label.Text = 'Simulation time ($\times10^3$)';
            app.SimulationTimeSlider = uislider(app.ControlsPanel);
            app.SimulationTimeSlider.Limits = [0 10];
            app.SimulationTimeSlider.Value = 5;
            app.StatusButton = uibutton(app.ControlsPanel, 'push');
            app.StatusButton.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.StatusButton.Interpreter = 'html';
            app.StatusButton.Text = '&#x2611;';
            % Show the figure after all components are created
            app.MMCQueuingModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mmc_app

            % Associate the Simulink Model
            app.Simulation = simulation('mmc_model');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MMCQueuingModelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MMCQueuingModelUIFigure)
        end
    end
end